classdef Robot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Q2dotmaxLabel            matlab.ui.control.Label
        EditField_vmax           matlab.ui.control.EditField
        QDotmaxLabel             matlab.ui.control.Label
        EditField_qmax           matlab.ui.control.EditField
        Velocity_PlanningButton  matlab.ui.control.Button
        EditField_zlink3_inv     matlab.ui.control.EditField
        EditField_ylink3_inv     matlab.ui.control.EditField
        EditField_xlink3_inv     matlab.ui.control.EditField
        InverseButton            matlab.ui.control.Button
        InversekineticCheckBox   matlab.ui.control.CheckBox
        ForwardkineticCheckBox   matlab.ui.control.CheckBox
        CylinderOnOFFButton      matlab.ui.control.Button
        cylinderonoff            matlab.ui.control.EditField
        InitializeButton         matlab.ui.control.Button
        EditField_6              matlab.ui.control.EditField
        EditField_5              matlab.ui.control.EditField
        EditField_4              matlab.ui.control.EditField
        EditField_3              matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EditField                matlab.ui.control.EditField
        Theta3Slider             matlab.ui.control.Slider
        Theta3SliderLabel        matlab.ui.control.Label
        Theta2Slider             matlab.ui.control.Slider
        Theta2SliderLabel        matlab.ui.control.Label
        Theta1Slider             matlab.ui.control.Slider
        Theta1SliderLabel        matlab.ui.control.Label
        EditField_yawlink3       matlab.ui.control.EditField
        EditField_pitchlink3     matlab.ui.control.EditField
        EditField_rolllink3      matlab.ui.control.EditField
        EditField_zlink3         matlab.ui.control.EditField
        EditField_ylink3         matlab.ui.control.EditField
        EditField_xlink3         matlab.ui.control.EditField
        EditField_yawlink2       matlab.ui.control.EditField
        EditField_pitchlink2     matlab.ui.control.EditField
        EditField_rolllink2      matlab.ui.control.EditField
        EditField_zlink2         matlab.ui.control.EditField
        EditField_ylink2         matlab.ui.control.EditField
        EditField_xlink2         matlab.ui.control.EditField
        Link3Label               matlab.ui.control.Label
        Link2Label               matlab.ui.control.Label
        Link1Label               matlab.ui.control.Label
        EditField_yawlink1       matlab.ui.control.EditField
        EditField_pitchlink1     matlab.ui.control.EditField
        EditField_rolllink1      matlab.ui.control.EditField
        EditField_zlink1         matlab.ui.control.EditField
        EditField_ylink1         matlab.ui.control.EditField
        EditField_xlink1         matlab.ui.control.EditField
        rollLabel                matlab.ui.control.Label
        pitchLabel               matlab.ui.control.Label
        yawLabel                 matlab.ui.control.Label
        zLabel                   matlab.ui.control.Label
        yLabel                   matlab.ui.control.Label
        xLabel                   matlab.ui.control.Label
        AngleasdegreeLabel       matlab.ui.control.Label
        ForwardButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %% Get data from text box (This is ARTICULATED ARM. so the input is 3 thetas)
            current_theta1=str2double(app.EditField.Value)*pi/180;
            current_theta2=str2double(app.EditField_2.Value)*pi/180;
            current_theta3=str2double(app.EditField_3.Value)*pi/180;
            
            if isnan(current_theta1)
                current_theta1=0;
                app.EditField.Value=num2str(current_theta1);
            end
            if isnan(current_theta2)
                current_theta2=0;
                app.EditField_2.Value=num2str(current_theta2);
            end
            if isnan(current_theta3)
                current_theta3=0;
                app.EditField_3.Value=num2str(current_theta3);
            end
            previous_theta1=str2double(app.EditField_4.Value)*pi/180;
            previous_theta2=str2double(app.EditField_5.Value)*pi/180;
            previous_theta3=str2double(app.EditField_6.Value)*pi/180;
            app.ForwardButton.Enable='off';
            app.EditField.Editable='off';
            app.EditField_2.Editable='off';
            app.EditField_3.Editable='off';
            for tt1=previous_theta1:(pi/10):current_theta1
                if(app.cylinderonoff.Value=="ON")
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,tt1,previous_theta2,previous_theta3,1);
                else
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,tt1,previous_theta2,previous_theta3,0);
                end
                %% Show end-effector point
                app.EditField_xlink1.Value=num2str(round(P1(1,1)));
                app.EditField_ylink1.Value=num2str(round(P1(2,1)));
                app.EditField_zlink1.Value=num2str(round(P1(3,1)));
                app.EditField_xlink2.Value=num2str(round(P2(1,1)));
                app.EditField_ylink2.Value=num2str(round(P2(2,1)));
                app.EditField_zlink2.Value=num2str(round(P2(3,1)));
                app.EditField_xlink3.Value=num2str(round(P3(1,1)));
                app.EditField_ylink3.Value=num2str(round(P3(2,1)));
                app.EditField_zlink3.Value=num2str(round(P3(3,1)));
                
                app.EditField_rolllink1.Value=num2str(round(rad2deg(RPY1(1,1))));
                app.EditField_pitchlink1.Value=num2str(round(rad2deg(RPY1(2,1))));
                app.EditField_yawlink1.Value=num2str(round(rad2deg(RPY1(3,1))));
                app.EditField_rolllink2.Value=num2str(round(rad2deg(RPY2(1,1))));
                app.EditField_pitchlink2.Value=num2str(round(rad2deg(RPY2(2,1))));
                app.EditField_yawlink2.Value=num2str(round(rad2deg(RPY2(3,1))));
                app.EditField_rolllink3.Value=num2str(round(rad2deg(RPY3(1,1))));
                app.EditField_pitchlink3.Value=num2str(round(rad2deg(RPY3(2,1))));
                app.EditField_yawlink3.Value=num2str(round(rad2deg(RPY3(3,1))));
                pause(0.05)
            end
            previous_theta1=current_theta1;
            for tt2=previous_theta2:(pi/10):current_theta2
                if(app.cylinderonoff.Value=="ON")
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,previous_theta1,tt2,previous_theta3,1);
                else
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,previous_theta1,tt2,previous_theta3,0);
                end
                %% Show end-effector point
                app.EditField_xlink1.Value=num2str(round(P1(1,1)));
                app.EditField_ylink1.Value=num2str(round(P1(2,1)));
                app.EditField_zlink1.Value=num2str(round(P1(3,1)));
                app.EditField_xlink2.Value=num2str(round(P2(1,1)));
                app.EditField_ylink2.Value=num2str(round(P2(2,1)));
                app.EditField_zlink2.Value=num2str(round(P2(3,1)));
                app.EditField_xlink3.Value=num2str(round(P3(1,1)));
                app.EditField_ylink3.Value=num2str(round(P3(2,1)));
                app.EditField_zlink3.Value=num2str(round(P3(3,1)));
                
                app.EditField_rolllink1.Value=num2str(round(rad2deg(RPY1(1,1))));
                app.EditField_pitchlink1.Value=num2str(round(rad2deg(RPY1(2,1))));
                app.EditField_yawlink1.Value=num2str(round(rad2deg(RPY1(3,1))));
                app.EditField_rolllink2.Value=num2str(round(rad2deg(RPY2(1,1))));
                app.EditField_pitchlink2.Value=num2str(round(rad2deg(RPY2(2,1))));
                app.EditField_yawlink2.Value=num2str(round(rad2deg(RPY2(3,1))));
                app.EditField_rolllink3.Value=num2str(round(rad2deg(RPY3(1,1))));
                app.EditField_pitchlink3.Value=num2str(round(rad2deg(RPY3(2,1))));
                app.EditField_yawlink3.Value=num2str(round(rad2deg(RPY3(3,1))));
                pause(0.05)
            end
            previous_theta2=current_theta2;
            for tt3=previous_theta3:(pi/10):current_theta3
                if(app.cylinderonoff.Value=="ON")
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,previous_theta1,previous_theta2,tt3,1);
                else
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,previous_theta1,previous_theta2,tt3,0);
                end
                %% Show end-effector point
                app.EditField_xlink1.Value=num2str(round(P1(1,1)));
                app.EditField_ylink1.Value=num2str(round(P1(2,1)));
                app.EditField_zlink1.Value=num2str(round(P1(3,1)));
                app.EditField_xlink2.Value=num2str(round(P2(1,1)));
                app.EditField_ylink2.Value=num2str(round(P2(2,1)));
                app.EditField_zlink2.Value=num2str(round(P2(3,1)));
                app.EditField_xlink3.Value=num2str(round(P3(1,1)));
                app.EditField_ylink3.Value=num2str(round(P3(2,1)));
                app.EditField_zlink3.Value=num2str(round(P3(3,1)));
                
                app.EditField_rolllink1.Value=num2str(round(rad2deg(RPY1(1,1))));
                app.EditField_pitchlink1.Value=num2str(round(rad2deg(RPY1(2,1))));
                app.EditField_yawlink1.Value=num2str(round(rad2deg(RPY1(3,1))));
                app.EditField_rolllink2.Value=num2str(round(rad2deg(RPY2(1,1))));
                app.EditField_pitchlink2.Value=num2str(round(rad2deg(RPY2(2,1))));
                app.EditField_yawlink2.Value=num2str(round(rad2deg(RPY2(3,1))));
                app.EditField_rolllink3.Value=num2str(round(rad2deg(RPY3(1,1))));
                app.EditField_pitchlink3.Value=num2str(round(rad2deg(RPY3(2,1))));
                app.EditField_yawlink3.Value=num2str(round(rad2deg(RPY3(3,1))));
                pause(0.05)
            end
            previous_theta3=current_theta3;
            app.EditField_4.Value=num2str(previous_theta1*180/pi);
            app.EditField_5.Value=num2str(previous_theta2*180/pi);
            app.EditField_6.Value=num2str(previous_theta3*180/pi);
            app.ForwardButton.Enable='on';
            app.EditField.Editable='on';
            app.EditField_2.Editable='on';
            app.EditField_3.Editable='on';
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value=num2str(changingValue);
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value=num2str(changingValue);
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value=num2str(changingValue);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.EditField_4.Value=num2str(0);
            app.EditField_5.Value=num2str(0);
            app.EditField_6.Value=num2str(0);
            [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,0,0,0,0);

            %% Show end-effector point
            app.EditField_xlink1.Value=num2str(round(P1(1,1)));
            app.EditField_ylink1.Value=num2str(round(P1(2,1)));
            app.EditField_zlink1.Value=num2str(round(P1(3,1)));
            app.EditField_xlink2.Value=num2str(round(P2(1,1)));
            app.EditField_ylink2.Value=num2str(round(P2(2,1)));
            app.EditField_zlink2.Value=num2str(round(P2(3,1)));
            app.EditField_xlink3.Value=num2str(round(P3(1,1)));
            app.EditField_ylink3.Value=num2str(round(P3(2,1)));
            app.EditField_zlink3.Value=num2str(round(P3(3,1)));
            
            app.EditField_rolllink1.Value=num2str(round(rad2deg(RPY1(1,1))));
            app.EditField_pitchlink1.Value=num2str(round(rad2deg(RPY1(2,1))));
            app.EditField_yawlink1.Value=num2str(round(rad2deg(RPY1(3,1))));
            app.EditField_rolllink2.Value=num2str(round(rad2deg(RPY2(1,1))));
            app.EditField_pitchlink2.Value=num2str(round(rad2deg(RPY2(2,1))));
            app.EditField_yawlink2.Value=num2str(round(rad2deg(RPY2(3,1))));
            app.EditField_rolllink3.Value=num2str(round(rad2deg(RPY3(1,1))));
            app.EditField_pitchlink3.Value=num2str(round(rad2deg(RPY3(2,1))));
            app.EditField_yawlink3.Value=num2str(round(rad2deg(RPY3(3,1))));

            app.EditField.Editable="on";
            app.EditField_2.Editable="on";
            app.EditField_3.Editable="on";
            app.EditField_xlink3_inv.Editable="on";
            app.EditField_ylink3_inv.Editable="on";
            app.EditField_zlink3_inv.Editable="on";
            app.InversekineticCheckBox.Enable="on";
            app.ForwardkineticCheckBox.Enable="on";
            app.Velocity_PlanningButton.Enable="on";
        end

        % Button pushed function: CylinderOnOFFButton
        function CylinderOnOFFButtonPushed(app, event)
            string a;
            if(app.cylinderonoff.Value=="ON")
                app.cylinderonoff.Value="OFF";
            else
                app.cylinderonoff.Value="ON";
            end
        end

        % Value changed function: ForwardkineticCheckBox
        function ForwardkineticCheckBoxValueChanged(app, event)
            value = app.ForwardkineticCheckBox.Value;

            switch value
                case 0
                    app.InversekineticCheckBox.Enable="on";
                    app.ForwardButton.Enable="off";
                    
                case 1
                    app.InversekineticCheckBox.Enable="off";
                    app.ForwardButton.Enable="on";
                
                    
            end
        end

        % Value changed function: InversekineticCheckBox
        function InversekineticCheckBoxValueChanged(app, event)
            value = app.InversekineticCheckBox.Value;
            switch value
                case 0
                    app.ForwardkineticCheckBox.Enable="on";
                   
                     app.InverseButton.Enable="off";
                case 1
                    app.ForwardkineticCheckBox.Enable="off";
            
                    app.InverseButton.Enable="on";
            end
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            px=str2double(app.EditField_xlink3_inv.Value);
            py=str2double(app.EditField_ylink3_inv.Value);
            pz=str2double(app.EditField_zlink3_inv.Value);

            if isnan(px)
                px=4;
                app.EditField_xlink3_inv.Value=num2str(px);
            end
            if isnan(py)
                py=0;
                app.EditField_ylink3_inv.Value=num2str(py);
            end
            if isnan(pz)
                pz=3;
                app.EditField_zlink3_inv.Value=num2str(pz);
            end

            [theta1,theta2,theta3,valid]=Inverse_Kinematic(px,py,pz);
            
            if(valid==0)
                msgbox("Invalid Value","Error","error");
                return
            end
            
            if(theta1<0)
                theta1=2*pi-theta1;
            end
            
            if(theta2<0)
                theta2=2*pi-theta1;
            end
            
            if(theta3<0)
                theta3=2*pi-theta3;
            end
            app.EditField.Value=num2str(theta1*180/pi);
            app.EditField_2.Value=num2str(theta2*180/pi);
            app.EditField_3.Value=num2str(theta3*180/pi);

        end

        % Button pushed function: Velocity_PlanningButton
        function Velocity_PlanningButtonPushed(app, event)
            qdotmax=str2double(app.EditField_qmax.Value)*pi/180;
            a=str2double(app.EditField_vmax.Value)*pi/180;
            current_theta1=str2double(app.EditField.Value)*pi/180;
            current_theta2=str2double(app.EditField_2.Value)*pi/180;
            current_theta3=str2double(app.EditField_3.Value)*pi/180;
            
            if isnan(current_theta1)
                current_theta1=0;
                app.EditField.Value=num2str(current_theta1);
            end
            if isnan(current_theta2)
                current_theta2=0;
                app.EditField_2.Value=num2str(current_theta2);
            end
            if isnan(current_theta3)
                current_theta3=0;
                app.EditField_3.Value=num2str(current_theta3);
            end
            previous_theta1=str2double(app.EditField_4.Value)*pi/180;
            previous_theta2=str2double(app.EditField_5.Value)*pi/180;
            previous_theta3=str2double(app.EditField_6.Value)*pi/180;

            delta_theta1=current_theta1-previous_theta1;
            delta_theta2=current_theta2-previous_theta2;
            delta_theta3=current_theta3-previous_theta3;

            if((delta_theta1/qdotmax)>(qdotmax/a)&&(delta_theta2/qdotmax)>(qdotmax/a)&&(delta_theta3/qdotmax)>(qdotmax/a))
                
            else
                msgbox("Invalid Value","Error","error");
                return
            end

            app.ForwardButton.Enable='off';
            app.EditField.Editable='off';
            app.EditField_2.Editable='off';
            app.EditField_3.Editable='off';


            app.InverseButton.Enable="off";
            app.EditField_xlink3_inv.Editable="off";
            app.EditField_ylink3_inv.Editable="off";
            app.EditField_zlink3_inv.Editable="off";


            %%write velocity planning in here
            taccelarate=qdotmax/a;
            tstable1=delta_theta1/qdotmax-qdotmax/a;
            tstable2=delta_theta2/qdotmax-qdotmax/a;
            tstable3=delta_theta3/qdotmax-qdotmax/a;
            
            ttotal=0;
            if((tstable1>=tstable2)&&(tstable1>=tstable3))
                ttotal=taccelarate*2+tstable1;
            elseif((tstable2>=tstable1)&&(tstable2>=tstable3))
                ttotal=taccelarate*2+tstable2;
            elseif((tstable3>=tstable1)&&(tstable3>=tstable2))
                ttotal=taccelarate*2+tstable3;
            end
            theta1=0;
            theta2=0;
            theta3=0;
            vtheta1=0;
            vtheta2=0;
            vtheta3=0;
            for i=0.001:0.001:ttotal
                if(i<taccelarate)
                    theta1=previous_theta1+a*i^2/2;
                    theta2=previous_theta2+a*i^2/2;
                    theta3=previous_theta3+a*i^2/2;
                    vtheta1=a*i;
                    vtheta2=a*i;
                    vtheta3=a*i;
                elseif((i>=taccelarate))
                    if(i<(tstable1+taccelarate))
                        theta1=previous_theta1+a*taccelarate^2/2+qdotmax*(i-taccelarate);
                        vtheta1=qdotmax;
                    elseif(i>=(tstable1+taccelarate)&&i<(tstable1+2*taccelarate))
                        theta1=previous_theta1+a*taccelarate^2/2+qdotmax*tstable1+a*(i-taccelarate-tstable1)^2/2;
                        vtheta1=qdotmax+a*(tstable1+taccelarate)-a*(i);
                    else
                        theta1=previous_theta1+a*taccelarate^2+qdotmax*tstable1;
                        vtheta1=0;
                    end
                    if(i<(tstable2+taccelarate))
                        theta2=previous_theta2+a*taccelarate^2/2+qdotmax*(i-taccelarate);
                        vtheta2=qdotmax;
                    elseif(i>=(tstable2+taccelarate)&&i<(tstable2+2*taccelarate))
                        theta2=previous_theta2+a*taccelarate^2/2+qdotmax*tstable2+a*(i-taccelarate-tstable2)^2/2;
                        vtheta2=qdotmax+a*(tstable2+taccelarate)-a*(i);
                    else
                        theta2=previous_theta2+a*taccelarate^2+qdotmax*tstable2;
                        vtheta2=0;
                    end
                    if(i<(tstable3+taccelarate))
                        theta3=previous_theta3+a*taccelarate^2/2+qdotmax*(i-taccelarate);
                        vtheta3=qdotmax;
                    elseif(i>=(tstable3+taccelarate)&&i<(tstable3+2*taccelarate))
                        theta3=previous_theta3+a*taccelarate^2/2+qdotmax*tstable3+a*(i-taccelarate-tstable3)^2/2;
                        vtheta3=qdotmax+a*(tstable3+taccelarate)-a*(i);
                    else
                        theta3=previous_theta3+a*taccelarate^2+qdotmax*tstable3;
                        vtheta3=0;
                    end
                end

                if(app.cylinderonoff.Value=="ON")
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,theta1,theta2,theta3,1);
                else
                    [P1,P2,P3,RPY1,RPY2,RPY3]=Forward_Kinatic(app.UIAxes,theta1,theta2,theta3,0);
                end
                %% Show end-effector point
                app.EditField_xlink1.Value=num2str(round(P1(1,1)));
                app.EditField_ylink1.Value=num2str(round(P1(2,1)));
                app.EditField_zlink1.Value=num2str(round(P1(3,1)));
                app.EditField_xlink2.Value=num2str(round(P2(1,1)));
                app.EditField_ylink2.Value=num2str(round(P2(2,1)));
                app.EditField_zlink2.Value=num2str(round(P2(3,1)));
                app.EditField_xlink3.Value=num2str(round(P3(1,1)));
                app.EditField_ylink3.Value=num2str(round(P3(2,1)));
                app.EditField_zlink3.Value=num2str(round(P3(3,1)));
                
                app.EditField_rolllink1.Value=num2str(round(rad2deg(RPY1(1,1))));
                app.EditField_pitchlink1.Value=num2str(round(rad2deg(RPY1(2,1))));
                app.EditField_yawlink1.Value=num2str(round(rad2deg(RPY1(3,1))));
                app.EditField_rolllink2.Value=num2str(round(rad2deg(RPY2(1,1))));
                app.EditField_pitchlink2.Value=num2str(round(rad2deg(RPY2(2,1))));
                app.EditField_yawlink2.Value=num2str(round(rad2deg(RPY2(3,1))));
                app.EditField_rolllink3.Value=num2str(round(rad2deg(RPY3(1,1))));
                app.EditField_pitchlink3.Value=num2str(round(rad2deg(RPY3(2,1))));
                app.EditField_yawlink3.Value=num2str(round(rad2deg(RPY3(3,1))));

                ve=Jacobian_Matrix(vtheta1,vtheta2,vtheta3,theta1,theta2,theta3);

            end
        

            app.ForwardButton.Enable='on';
            app.EditField.Editable='on';
            app.EditField_2.Editable='on';
            app.EditField_3.Editable='on';

            app.InverseButton.Enable="on";
            app.EditField_xlink3_inv.Editable="on";
            app.EditField_ylink3_inv.Editable="on";
            app.EditField_zlink3_inv.Editable="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot visualize')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tag = 'btn_forward';
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = 'Forward';
            app.AngleasdegreeLabel = uilabel(app.UIFigure);
            app.AngleasdegreeLabel.Text = 'Angle as degree';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.yawLabel = uilabel(app.UIFigure);
            app.yawLabel.Text = 'yaw';
            app.pitchLabel = uilabel(app.UIFigure);
            app.pitchLabel.Text = 'pitch';
            app.rollLabel = uilabel(app.UIFigure);
            app.rollLabel.Text = 'roll';
            app.EditField_xlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_xlink1.Editable = 'off';
            app.EditField_ylink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink1.Editable = 'off';
            app.EditField_zlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink1.Editable = 'off';
            app.EditField_rolllink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_rolllink1.Editable = 'off';
            app.EditField_pitchlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_pitchlink1.Editable = 'off';
            app.EditField_yawlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_yawlink1.Editable = 'off';
            app.Link1Label = uilabel(app.UIFigure);
            app.Link1Label.Text = 'Link 1';
            app.Link2Label = uilabel(app.UIFigure);
            app.Link2Label.Text = 'Link 2';
            app.Link3Label = uilabel(app.UIFigure);
            app.Link3Label.Text = 'Link 3';
            app.EditField_xlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_xlink2.Editable = 'off';
            app.EditField_ylink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink2.Editable = 'off';
            app.EditField_zlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink2.Editable = 'off';
            app.EditField_rolllink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_rolllink2.Editable = 'off';
            app.EditField_pitchlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_pitchlink2.Editable = 'off';
            app.EditField_yawlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_yawlink2.Editable = 'off';
            app.EditField_xlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_xlink3.Editable = 'off';
            app.EditField_ylink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink3.Editable = 'off';
            app.EditField_zlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink3.Editable = 'off';
            app.EditField_rolllink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_rolllink3.Editable = 'off';
            app.EditField_pitchlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_pitchlink3.Editable = 'off';
            app.EditField_yawlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_yawlink3.Editable = 'off';
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [0 360];
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [0 360];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [0 360];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Editable = 'off';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.cylinderonoff = uieditfield(app.UIFigure, 'text');
            app.cylinderonoff.Editable = 'off';
            app.CylinderOnOFFButton = uibutton(app.UIFigure, 'push');
            app.CylinderOnOFFButton.ButtonPushedFcn = createCallbackFcn(app, @CylinderOnOFFButtonPushed, true);
            app.CylinderOnOFFButton.Text = 'Cylinder On/OFF';
            app.ForwardkineticCheckBox = uicheckbox(app.UIFigure);
            app.ForwardkineticCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForwardkineticCheckBoxValueChanged, true);
            app.ForwardkineticCheckBox.Enable = 'off';
            app.ForwardkineticCheckBox.Text = 'Forward kinetic';
            app.InversekineticCheckBox = uicheckbox(app.UIFigure);
            app.InversekineticCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversekineticCheckBoxValueChanged, true);
            app.InversekineticCheckBox.Enable = 'off';
            app.InversekineticCheckBox.Text = 'Inverse kinetic';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Enable = 'off';
            app.InverseButton.Text = 'Inverse';
            app.EditField_xlink3_inv = uieditfield(app.UIFigure, 'text');
            app.EditField_xlink3_inv.Editable = 'off';
            app.EditField_ylink3_inv = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink3_inv.Editable = 'off';
            app.EditField_zlink3_inv = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink3_inv.Editable = 'off';
            app.Velocity_PlanningButton = uibutton(app.UIFigure, 'push');
            app.Velocity_PlanningButton.ButtonPushedFcn = createCallbackFcn(app, @Velocity_PlanningButtonPushed, true);
            app.Velocity_PlanningButton.Enable = 'off';
            app.Velocity_PlanningButton.Text = 'Velocity_Planning';
            app.EditField_qmax = uieditfield(app.UIFigure, 'text');
            app.QDotmaxLabel = uilabel(app.UIFigure);
            app.QDotmaxLabel.Text = 'Q Dot max';
            app.EditField_vmax = uieditfield(app.UIFigure, 'text');
            app.Q2dotmaxLabel = uilabel(app.UIFigure);
            app.Q2dotmaxLabel.Text = 'Q 2dot max';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end